classdef Start_develop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BACKButton                    matlab.ui.control.Button
        STARTButton                   matlab.ui.control.Button
        SignalsonplotLabel            matlab.ui.control.Label
        errorobjectCheckBox           matlab.ui.control.CheckBox
        OutputSignalobjectCheckBox    matlab.ui.control.CheckBox
        SteeringSignalobjectCheckBox  matlab.ui.control.CheckBox
        ReferenceSignalCheckBox       matlab.ui.control.CheckBox
        KdEditField                   matlab.ui.control.NumericEditField
        KdEditFieldLabel              matlab.ui.control.Label
        KiEditField                   matlab.ui.control.NumericEditField
        KiEditFieldLabel              matlab.ui.control.Label
        KpEditField                   matlab.ui.control.NumericEditField
        KpEditFieldLabel              matlab.ui.control.Label
        FinalValueSlider              matlab.ui.control.Slider
        FinalValueSliderLabel         matlab.ui.control.Label
        ControllerSpecsLabel          matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        State
        UdpPort
        Data
        Timer1
        Timer2
        Message
        Values
        Cnt
        Temp_off
        Temp_index
        Send_mess
    end
    
methods (Access = private)
    function Loop_Fcn(app, varargin)
        if app.State == 1
            app.Data = read(app.UdpPort, 1);
            app.Message(end+1) = app.Data;
            if app.Data(end) == 59
                app.Values(end,mod(app.Cnt, 3)+1) = str2double(char(app.Message(1:end-1)));
                if app.Temp_off ~= -10
                    app.Values(app.Temp_index,:) = app.Temp_off;
                    app.Temp_off = -10;
                    app.Values = zeros(1,3);
                    cla(app.UIAxes);
                end
                app.Message = [];
                app.Values(end+1,1) = app.Values(end,1);
                app.Cnt = 0;
            end
            if app.Data(end) == 44
                app.Values(end,mod(app.Cnt, 3)+1) = str2double(char(app.Message(1:end-1)));
                app.Message = [];
                app.Cnt = app.Cnt + 1;
            end
        end
    end
        
    function Send_msg(app)
        if isempty(app.Send_mess)
            app.Send_mess = ' ';
        end
        u = udpport;
        write(u, char(app.Send_mess), "char", "192.168.113.5", 25565);
        delete(u);
     end
        
     function Timer_Fcn(app)
        % hold(app.UIAxes,'on')
        if app.OutputSignalobjectCheckBox.Value == true
        plot(app.UIAxes,app.Values(:,1),'b');
        end
        % if app.SteeringSignalobjectCheckBox.Value == true
        % plot(app.UIAxes,app.Values(:,2),'r');
        % end
        % if app.errorobjectCheckBox.Value == true
        % plot(app.UIAxes,app.Values(:,3),'g');
        % end
        % hold(app.UIAxes,'off')
     end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.State = 2;
            app.Message = [];
            app.Values = zeros(1,3);
            app.Cnt = 0;
            app.Temp_off = -10;
            app.Timer1 = timer("ExecutionMode","fixedRate","Period",0.001,"BusyMode","drop","TimerFcn",@(varargin)app.Loop_Fcn);
            app.Timer2 = timer("ExecutionMode","fixedRate","Period",0.001,"BusyMode","drop","TimerFcn",@(varargin)app.Timer_Fcn);
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            if app.State == 1
                app.State = 2;
            elseif app.State == 2
                app.State = 1;
            end
            if app.State == 1
                app.UdpPort = udpport("LocalPort", 25565);
                app.STARTButton.Text = 'STOP';
                start(app.Timer1);
                start(app.Timer2);
            elseif app.State == 2
                app.STARTButton.Text = 'START';
                stop(app.Timer1);
                stop(app.Timer2);
                clear app.UdpPort;
                app.UdpPort = [];
                app.Temp_off = app.Values(end-1,:);
                app.Temp_index = size(app.Values,1);
                app.Cnt = 0;
            end
        end

        % Button pushed function: BACKButton
        function BACKButtonPushed(app, event)
            delete(app);
            aplication;
        end

        % Value changed function: FinalValueSlider
        function SliderValueChanged(app, event)
            app.Send_mess = "y"+num2str(app.FinalValueSlider.Value) +",p"+num2str(app.KpEditField.Value) + ",i" + num2str(app.KiEditField.Value) + ",d" + num2str(app.KdEditField.Value);
            % app.Send_mess = num2str(value);
            Send_msg(app);
            app.Temp_off = app.Values(end-1,:);
            app.Temp_index = size(app.Values,1);
        end

        % Value changed function: KpEditField
        function KpEditFieldValueChanged(app, event)
            app.Send_mess = "y"+num2str(app.FinalValueSlider.Value) +",p"+num2str(app.KpEditField.Value) + ",i" + num2str(app.KiEditField.Value) + ",d" + num2str(app.KdEditField.Value);
            % app.Send_mess = num2str(value);
            Send_msg(app);
            app.Temp_off = app.Values(end-1,:);
            app.Temp_index = size(app.Values,1);
        end

        % Value changed function: KiEditField
        function KiEditFieldValueChanged(app, event)
            app.Send_mess = "y"+num2str(app.FinalValueSlider.Value) +",p"+num2str(app.KpEditField.Value) + ",i" + num2str(app.KiEditField.Value) + ",d" + num2str(app.KdEditField.Value);
            % app.Send_mess = num2str(value);
            Send_msg(app);
            app.Temp_off = app.Values(end-1,:);
            app.Temp_index = size(app.Values,1);
        end

        % Value changed function: KdEditField
        function KdEditFieldValueChanged(app, event)
            app.Send_mess = "y"+num2str(app.FinalValueSlider.Value) +",p"+num2str(app.KpEditField.Value) + ",i" + num2str(app.KiEditField.Value) + ",d" + num2str(app.KdEditField.Value);
            % app.Send_mess = num2str(value);
            Send_msg(app);
            app.Temp_off = app.Values(end-1,:);
            app.Temp_index = size(app.Values,1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Resources', 'poznan-university-of-technology.png');
            app.ControllerSpecsLabel = uilabel(app.UIFigure);
            app.ControllerSpecsLabel.Text = 'Controller Specs:';
            app.FinalValueSliderLabel = uilabel(app.UIFigure);
            app.FinalValueSliderLabel.Text = 'Final Value';
            app.FinalValueSlider = uislider(app.UIFigure);
            app.FinalValueSlider.Limits = [0 360];
            app.FinalValueSlider.MajorTicks = [0 90 180 270 360];
            app.FinalValueSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.FinalValueSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100 105 110 115 120 125 130 135 140 145 150 155 160 165 170 175 180 185 190 195 200 205 210 215 220 225 230 235 240 245 250 255 260 265 270 275 280 285 290 295 300 305 310 315 320 325 330 335 340 345 350 355 360];
            app.KpEditFieldLabel = uilabel(app.UIFigure);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.UIFigure, 'numeric');
            app.KpEditField.ValueChangedFcn = createCallbackFcn(app, @KpEditFieldValueChanged, true);
            app.KiEditFieldLabel = uilabel(app.UIFigure);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.UIFigure, 'numeric');
            app.KiEditField.ValueChangedFcn = createCallbackFcn(app, @KiEditFieldValueChanged, true);
            app.KdEditFieldLabel = uilabel(app.UIFigure);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.UIFigure, 'numeric');
            app.KdEditField.ValueChangedFcn = createCallbackFcn(app, @KdEditFieldValueChanged, true);
            app.ReferenceSignalCheckBox = uicheckbox(app.UIFigure);
            app.ReferenceSignalCheckBox.Text = 'Reference Signal';
            app.SteeringSignalobjectCheckBox = uicheckbox(app.UIFigure);
            app.SteeringSignalobjectCheckBox.Text = 'Steering Signal (object)';
            app.OutputSignalobjectCheckBox = uicheckbox(app.UIFigure);
            app.OutputSignalobjectCheckBox.Text = 'Output Signal (object)';
            app.OutputSignalobjectCheckBox.Value = true;
            app.errorobjectCheckBox = uicheckbox(app.UIFigure);
            app.errorobjectCheckBox.Text = 'error (object)';
            app.SignalsonplotLabel = uilabel(app.UIFigure);
            app.SignalsonplotLabel.Text = 'Signals on plot:';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.BACKButton = uibutton(app.UIFigure, 'push');
            app.BACKButton.ButtonPushedFcn = createCallbackFcn(app, @BACKButtonPushed, true);
            app.BACKButton.Text = 'BACK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Start_develop

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end