classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BACKButton                    matlab.ui.control.Button
        Slider2                       matlab.ui.control.Slider
        Slider2Label                  matlab.ui.control.Label
        MessagetosendEditField        matlab.ui.control.EditField
        MessagetosendEditFieldLabel   matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        SendButton                    matlab.ui.control.Button
        RecivedmessageEditField       matlab.ui.control.EditField
        RecivedmessageEditFieldLabel  matlab.ui.control.Label
        ListenButton                  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        State
        UdpPort
        Data
        Timer
        Message
        Values
        Cnt
    end
    
methods (Access = public)
    function Loop_Fcn(app, varargin)
        if app.State == 1
            app.Data = read(app.UdpPort, 1);
            app.Message(end+1) = app.Data;
%             disp(app.Message);
            if app.Data(end) == 59
                app.Values(end,mod(app.Cnt, 3)+1) = str2double(char(app.Message(1:end-1)));
                app.RecivedmessageEditField.Value = sprintf('%d, %d, %d', app.Values(end,:));
                app.Message = [];
                app.Values(end+1,1) = 0;
                app.Cnt = app.Cnt + 1;
            end
            if app.Data(end) == 44
                app.Values(end,mod(app.Cnt, 3)+1) = str2double(char(app.Message(1:end-1)));
%                 disp(str2double(char(app.Message(1:end-1))));
                app.Message = [];
                app.Cnt = app.Cnt + 1;
            end
        end
%         plot(app.UIAxes,app.Values(:,1)); hold on;
%         plot(app.UIAxes,app.Values(:,2),'Color','r');
%         plot(app.UIAxes,app.Values(:,3),'Color','g');hold off;
    end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.State = 2;
            app.Message = [];
            app.Values = zeros(1,3);
            app.Cnt = 0;
            app.UdpPort = udpport("LocalPort", 25565);
            app.Timer = timer("ExecutionMode","fixedRate","Period",0.001,"BusyMode","queue","TimerFcn",@(varargin)app.Loop_Fcn);            
        end

        % Button pushed function: ListenButton
        function ListenButtonPushed(app, event)
            if app.State == 1
                app.State = 2;
            elseif app.State == 2
                app.State = 1;
            end
            if app.State == 1
                fopen(app.UdpPort);
                app.Label.Text = 'listening';
                app.ListenButton.Text = 'STOP';
                start(app.Timer);
            elseif app.State == 2
                fclose(app.UdpPort);
                app.Label.Text = '';
                app.ListenButton.Text = 'Listen';
                stop(app.Timer);
            end
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
        message = app.MessagetosendEditField.Value;
        if isempty(message)
            message = ' ';
        end
        u = udpport;
        write(u, char(message), "char", "192.168.113.5", 25565);
        delete(u);
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
    value = app.Slider2.Value;
    valueAsString = num2str(value);
    app.MessagetosendEditField.Value = valueAsString;
    SendButtonPushed(app, event);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: BACKButton
        function BACKButtonPushed(app, event)
            delete(app)
            aplication;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ListenButton = uibutton(app.UIFigure, 'push');
            app.ListenButton.ButtonPushedFcn = createCallbackFcn(app, @ListenButtonPushed, true);
            app.ListenButton.Text = 'Listen';
            app.RecivedmessageEditFieldLabel = uilabel(app.UIFigure);
            app.RecivedmessageEditFieldLabel.Text = 'Recived message';
            app.RecivedmessageEditField = uieditfield(app.UIFigure, 'text');
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.MessagetosendEditFieldLabel = uilabel(app.UIFigure);
            app.MessagetosendEditFieldLabel.Text = 'Message to send';
            app.MessagetosendEditField = uieditfield(app.UIFigure, 'text');
            app.Slider2Label = uilabel(app.UIFigure);
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.Limits = [0 360];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider2.Value = 180;
            app.BACKButton = uibutton(app.UIFigure, 'push');
            app.BACKButton.ButtonPushedFcn = createCallbackFcn(app, @BACKButtonPushed, true);
            app.BACKButton.Text = 'BACK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end